classdef Function_Plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure                   % Function Plotter
        fnInput       matlab.ui.control.EditField       
        fnInputLbl    matlab.ui.control.Label            % Enter
        UIAxes        matlab.ui.control.UIAxes          
        plotBtn       matlab.ui.control.Button           % Plot
        errorLbl      matlab.ui.control.Label           
        maxXInput     matlab.ui.control.NumericEditField % [-Inf Inf]
        minXInput     matlab.ui.control.NumericEditField % [-Inf Inf]
        domainLbl     matlab.ui.control.Label            % Domain
        domainX       matlab.ui.control.Label            %  ≤ x ≤
        plotListLbl   matlab.ui.control.Label            % Plotted Functions
        plotList      matlab.ui.control.ListBox         
        clearAxesBtn  matlab.ui.control.Button           % Clear Axes
        hidePlotBtn   matlab.ui.control.Button           % Hide Plot
        deletePlotBtn matlab.ui.control.Button           % Delete Plot
        showPlotBtn   matlab.ui.control.Button           % Show Plot
    end


    properties (Access = private)
        plottedLines = [] % Array of line plot objects on axes
    end
    
%     methods (Access = public)
%         function error = validateInput(app, fnStr, minX, maxX)
% 
%             error = '';
%             try
%                 x = linspace(minX,maxX);
%                 fn = eval(fnStr);
%             catch e
%                 switch e.identifier
%                     case 'MATLAB:m_missing_operator'
%                         error = 'Operator missing, Please check if input function is complete and in correct format';
%                     case 'MATLAB:m_incomplete_statement'
%                         error = 'Invalid Function, Please check if input function is complete and in correct format';
%                     case 'MATLAB:UndefinedFunction'
%                         error = 'Undefined Variable, Please enter a function of x';
%                     otherwise
%                        display(e.identifier);
%                        display(e.message);
%                        error = e.message;
%                 end
% %                 display(error);
%                 return;
%             end
%             %
%             if any(strcmp(app.plotList.Items, app.fnInput.Value))
%                 error = 'Function is already plotted, Please remove plot to replot using different domain';
%             end
%             %
%             if minX >= maxX
%                 error = 'Invalid Domain Entered, Plese make sure the maximum x value is higher than the minimum';
%             end            
%         end
%     end

    methods (Access = private)
        
        function results = plotFunc(app, fnStr, x)
            hold(app.UIAxes, 'on');
            fn = eval(fnStr);
            if size(fn) == 1
                newPlot = plot(app.UIAxes, x, repmat(fn,size(x)));
            else
                newPlot = plot(app.UIAxes, x, fn);
            end
            
            app.plottedLines = [app.plottedLines newPlot];
            app.plotList.Items = [app.plotList.Items, app.fnInput.Value];
        end

        function results = handleInput(app)
            fnStr = app.fnInput.Value;
            
            minX = app.minXInput.Value;
            maxX = app.maxXInput.Value;
            
            
            error = Validator.validateInput(fnStr, minX, maxX, app.plotList.Items);
            displayError(app, error);
            if strcmp(error, '') == 1
                x = linspace(minX,maxX);
                plotFunc(app, fnStr, x);  
            end
        end


        function results = displayError(app, errorMsg)
            app.errorLbl.Text = errorMsg;
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [200 200 700 450];
%             displayError(app, '');

%             colorStr = '#dac9bf';
%             color = sscanf(colorStr(2:end),'%2x%2x%2x',[1 3])/255;
%             app.UIFigure.Color = color;
        end

        % plotBtn button pushed function
        function plotClicked(app)
            handleInput(app);
        end

        % clearAxesBtn button pushed function
        function clearClicked(app)
            app.UIAxes.cla;
            app.plotList.Items = {};
            app.plottedLines = [];
            displayError(app, '')
        end

        % hidePlotBtn button pushed function
        function hidePlotClicked(app)
            [~, index] = ismember(app.plotList.Value, app.plotList.Items);
            set(app.plottedLines(index),'Visible','off')
            if isempty(index)
                displayError(app, 'Please select a plot from the list')
            else
                displayError(app, '')
            end
        end

        % showPlotBtn button pushed function
        function showPlotClicked(app)
            [~, index] = ismember(app.plotList.Value, app.plotList.Items);
            set(app.plottedLines(index),'Visible','on')
            if isempty(index)
                displayError(app, 'Please select a plot from the list')
            else
                displayError(app, '')
            end
        end

        % deletePlotBtn button pushed function
        function deletePlotClicked(app)
            [~, index] = ismember(app.plotList.Value, app.plotList.Items);
            delete(app.plottedLines(index));
            app.plottedLines(index) = [];
            app.plotList.Items(index) = [];
            if isempty(index)
                displayError(app, 'Please select a plot from the list')
            else
                displayError(app, '')
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Function Plotter';
            setAutoResize(app, app.UIFigure, true)
            app.fnInput = uieditfield(app.UIFigure, 'text');
            app.fnInputLbl = uilabel(app.UIFigure);
            app.fnInputLbl.Text = {'Enter'; 'Function'};
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.plotBtn = uibutton(app.UIFigure, 'push');
            app.plotBtn.ButtonPushedFcn = createCallbackFcn(app, @plotClicked);
            app.plotBtn.Text = 'Plot';
            app.errorLbl = uilabel(app.UIFigure);
            app.errorLbl.Text = '';
            app.maxXInput = uieditfield(app.UIFigure, 'numeric');
            app.minXInput = uieditfield(app.UIFigure, 'numeric');
            app.domainLbl = uilabel(app.UIFigure);
            app.domainLbl.Text = 'Domain';
            app.domainX = uilabel(app.UIFigure);
            app.domainX.Text = ' ≤ x ≤';
            app.plotListLbl = uilabel(app.UIFigure);
            app.plotListLbl.Text = 'Plotted Functions';
            app.plotList = uilistbox(app.UIFigure);
            app.plotList.Items = {};
            app.plotList.Value = {};
            app.clearAxesBtn = uibutton(app.UIFigure, 'push');
            app.clearAxesBtn.ButtonPushedFcn = createCallbackFcn(app, @clearClicked);
            app.clearAxesBtn.Text = 'Clear Axes';
            app.hidePlotBtn = uibutton(app.UIFigure, 'push');
            app.hidePlotBtn.ButtonPushedFcn = createCallbackFcn(app, @hidePlotClicked);
            app.hidePlotBtn.Text = 'Hide Plot';
            app.deletePlotBtn = uibutton(app.UIFigure, 'push');
            app.deletePlotBtn.ButtonPushedFcn = createCallbackFcn(app, @deletePlotClicked);
            app.deletePlotBtn.Text = 'Delete Plot';
            app.showPlotBtn = uibutton(app.UIFigure, 'push');
            app.showPlotBtn.ButtonPushedFcn = createCallbackFcn(app, @showPlotClicked);
            app.showPlotBtn.Text = 'Show Plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Function_Plotter()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end